classdef SMDRSS_anglePopup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        HereyoucanchangetheanglesinwhichthemeasurementstakeplaceLabel  matlab.ui.control.Label
        CustommeasurementsteppinganglesLabel  matlab.ui.control.Label
        UITable   matlab.ui.control.Table
        OKButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        parent % The parent app which called this popup
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent, anglesT)
            % Get data from main app
            app.parent = parent;
            app.UITable.Data = anglesT;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % call second app and input angles into it
            update_angles(app.parent, app.UITable.Data);
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            update_angles(app.parent, app.UITable.Data);
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Angles'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CustommeasurementsteppinganglesLabel = uilabel(app.UIFigure);
            app.CustommeasurementsteppinganglesLabel.Text = 'Custom measurement stepping angles';
            app.HereyoucanchangetheanglesinwhichthemeasurementstakeplaceLabel = uilabel(app.UIFigure);
            app.HereyoucanchangetheanglesinwhichthemeasurementstakeplaceLabel.Text = {'Here you can change the '; 'angles in which the '; 'measurements take place'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SMDRSS_anglePopup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end