classdef SMDRSS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MICROPHONEMAPPINGTab          matlab.ui.container.Tab
        MIC_GRID                      matlab.ui.container.GridLayout
        CalFreqHzEditField            matlab.ui.control.EditField
        CalibrationFrequencyHzLabel   matlab.ui.control.Label
        StartmicEditField             matlab.ui.control.NumericEditField
        StartmicrophonechannelsfromLabel  matlab.ui.control.Label
        PassesEditField               matlab.ui.control.EditField
        PassesEditFieldLabel          matlab.ui.control.Label
        CenterangledegEditField       matlab.ui.control.EditField
        CenterangledegEditFieldLabel  matlab.ui.control.Label
        REditField                    matlab.ui.control.EditField
        REditFieldLabel               matlab.ui.control.Label
        LEditField                    matlab.ui.control.EditField
        LEditFieldLabel               matlab.ui.control.Label
        AnglesectordegEditField       matlab.ui.control.EditField
        AnglesectordegEditFieldLabel  matlab.ui.control.Label
        MeasurementsEditField         matlab.ui.control.EditField
        MeasurementsEditFieldLabel    matlab.ui.control.Label
        ArrayrotDropDown              matlab.ui.control.DropDown
        ArrayrotDropDownLabel         matlab.ui.control.Label
        MicrophonesEditField          matlab.ui.control.EditField
        MicrophonesEditFieldLabel     matlab.ui.control.Label
        SynchronousCheckBox           matlab.ui.control.CheckBox
        TiphoveroverelementstoseemoreinfoLabel  matlab.ui.control.Label
        EDITANGLESButton              matlab.ui.control.Button
        SpeakerchannelsLabel          matlab.ui.control.Label
        EDITButton                    matlab.ui.control.Button
        APPLYButton                   matlab.ui.control.Button
        SAVEButton                    matlab.ui.control.Button
        LOADButton                    matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        SETUPTab                      matlab.ui.container.Tab
        SETUP_GRID                    matlab.ui.container.GridLayout
        MeasurementTimesEditField     matlab.ui.control.NumericEditField
        ContinuousMeasurementTimesLabel  matlab.ui.control.Label
        BufferSizeDropDown            matlab.ui.control.DropDown
        BufferSizeDropDownLabel       matlab.ui.control.Label
        SamplerateDropDown            matlab.ui.control.DropDown
        SamplerateDropDownLabel       matlab.ui.control.Label
        ASIODeviceDropDown            matlab.ui.control.DropDown
        ASIODeviceDropDownLabel       matlab.ui.control.Label
        SETUPWARNINGLabel             matlab.ui.control.Label
        ASIOSETTINGSButton            matlab.ui.control.Button
        RESETButton                   matlab.ui.control.Button
        SAVEANDCONTINUEButton         matlab.ui.control.Button
        SPACEFORMICARMROTATIONLabel   matlab.ui.control.Label
        CALIBRATIONTab                matlab.ui.container.Tab
        CALIB_GRID                    matlab.ui.container.GridLayout
        MEASTONEDropDown              matlab.ui.control.DropDown
        MEASTONEDropDownLabel         matlab.ui.control.Label
        CLIPLamp                      matlab.ui.control.Lamp
        CLIPLampLabel                 matlab.ui.control.Label
        GaindBKnob                    matlab.ui.control.Knob
        GaindBLabel                   matlab.ui.control.Label
        CLIPSAMPLESEditField          matlab.ui.control.NumericEditField
        CLIPSAMPLESEditFieldLabel     matlab.ui.control.Label
        HEADROOMdBFSEditField         matlab.ui.control.NumericEditField
        HEADROOMdBFSLabel             matlab.ui.control.Label
        LOGTextArea                   matlab.ui.control.TextArea
        LOGTextAreaLabel              matlab.ui.control.Label
        MINIMUMSNRdBEditField         matlab.ui.control.NumericEditField
        MINIMUMSNRdBEditField_2Label  matlab.ui.control.Label
        GainEditField                 matlab.ui.control.NumericEditField
        CALIBWARNINGLabel             matlab.ui.control.Label
        CALIBTable                    matlab.ui.control.Table
        TESTSOUNDSNRButton            matlab.ui.control.Button
        CALIBRATEButton               matlab.ui.control.Button
        MEASURENOISEFLOORButton       matlab.ui.control.Button
        FINISHANDCONTINUEButton       matlab.ui.control.Button
        MEASUREMENTTab                matlab.ui.container.Tab
        MEAS_GRID                     matlab.ui.container.GridLayout
        RotationangleEditField        matlab.ui.control.NumericEditField
        RotationangleEditFieldLabel   matlab.ui.control.Label
        LOGTextArea_2                 matlab.ui.control.TextArea
        LOGTextArea_2Label            matlab.ui.control.Label
        RETRYPASSButton               matlab.ui.control.Button
        SAVEMEASUREMENTButton         matlab.ui.control.Button
        AdditionalmeasurementLabel    matlab.ui.control.Label
        ADDMEASUREMENTButton          matlab.ui.control.Button
        RETRYMEASUREMENTButton        matlab.ui.control.Button
        MEASWARNINGLabel              matlab.ui.control.Label
        MEASTable                     matlab.ui.control.Table
        RESETButton_2                 matlab.ui.control.Button
        MEASUREButton                 matlab.ui.control.Button
        REVIEWTab                     matlab.ui.container.Tab
        REVIEW_GRID                   matlab.ui.container.GridLayout
        StopBandEditField             matlab.ui.control.NumericEditField
        StopBandEditFieldLabel        matlab.ui.control.Label
        StartBandEditField            matlab.ui.control.NumericEditField
        StartBandEditFieldLabel       matlab.ui.control.Label
        EXPORTButton                  matlab.ui.control.Button
        AllLabel                      matlab.ui.control.Label
        POSCheckBox                   matlab.ui.control.CheckBox
        MICCheckBox                   matlab.ui.control.CheckBox
        PASSCheckBox                  matlab.ui.control.CheckBox
        MEASUREMENTDropDownLabel_5    matlab.ui.control.Label
        MEASUREMENTDropDownLabel_4    matlab.ui.control.Label
        MEASUREMENTDropDownLabel_3    matlab.ui.control.Label
        MEASUREMENTDropDownLabel_2    matlab.ui.control.Label
        ANALYSISDropDown              matlab.ui.control.DropDown
        PASSDropDown                  matlab.ui.control.DropDown
        MICDropDown                   matlab.ui.control.DropDown
        POINTDropDown                 matlab.ui.control.DropDown
        DRAWINWINDOWButton            matlab.ui.control.Button
        LOADMEASUREMENTButton         matlab.ui.control.Button
        DRAWButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % TODO: add normalisation
    %       finish figure draw scripts
    %       improve state control
    %       add more rigorous error handling
    %       externalise what can be externalised
    
    properties (Access = private)
        config      % config data structure
        meas        % measurement data structure
        name        % name of measurement/save
        actual_meas % counter which stores the measurement number
        pass        % counter which stores pass number
        mics        % number of mics in edit field
        angl_popup  % popup window with angle settings
        angle_name  % string of azimuth or elevation based on settings
        PlayRec     % player recorder object
        on_thresh   % onset detection threshold 
        passes      % number of passes
        dev_conf    % device config (contains supported samplerates and buffer sizes)
        pnt         % simplified point structure
        meas_tone   % loadede measurement tone   
        meas_time   % measurement time for continuous measurement
        items       % dropdown items structure/cache
        dr_mics     % number of mics in draw tab
        dr_passes   % number of passes in draw tab
        dr_points   % number of points in draw tab
        mode        % draw in window or externally    
    end
    
    properties (Access = public)
        cal_vect
    end
    
    methods (Access = private)
        % ---- return right angles for microphones
        function [az,el] = eval_angle(app)
            %fill arrays with zeros
            az = zeros(app.mics,1); % azimuth
            el = zeros(app.mics,1); % elevation
            app.angle_name = "Azimuth";

            if isequal(app.config.array_dir,'Static')
                % Static array configuration
                % every angle is equal to the initial
                for mic = 1:app.mics
                    az(mic) = app.config.mic(mic).az(app.pass+1);
                    el(mic) = app.config.mic(mic).el(app.pass+1);
                end
                return
            end

            if isequal(app.config.array_dir,'Horizontal')
                % Horizontal array configuration
                for mic = 1:app.mics
                    % array moves horizontally => az changes, el = const;
                    az(mic) = app.config.mic(mic).az(app.pass+1)+app.config.angles(app.actual_meas+1);
                    el(mic) = app.config.mic(mic).el(app.pass+1);
                end
                return
            end

            app.angle_name = "Elevation"; 
            if isequal(app.config.array_dir,'Vertical')
                % Vertical array configuration
                for mic = 1:app.mics
                    % array moves vertically => el = changes, az const;
                    az(mic) = app.config.mic(mic).az(app.pass+1);
                    el(mic) = app.config.mic(mic).el(app.pass+1)+app.config.angles(app.actual_meas+1);
                end
                return
            end
        end
        % ---- process microphone data
        function process_mic_data(app,T)
            for pass_ = 1:app.config.passes
                for mic = 1:app.mics
                    ind = mic + app.mics*(pass_-1);
                    app.config.mic(mic).az(pass_)   = T.(3)(ind);
                    app.config.mic(mic).el(pass_)   = T.(4)(ind);
                    if pass_ == 1 % channels are evaluated only for first cells
                        app.config.mic(mic).chan = T.(5)(mic);
                    end
                end % app.mics
            end % app.config.passes
        end
        % ---- refresh config UI
        function refresh_config_UI(app)
            % angle values
            app.CenterangledegEditField.Value = num2str(app.config.meas_center);
            app.AnglesectordegEditField.Value = num2str(app.config.meas_angle);
            % speaker mapping
            app.REditField.Value = num2str(app.config.spkrR);
            app.LEditField.Value = num2str(app.config.spkrL);
            % measurements
            app.MeasurementsEditField.Value = num2str(app.config.n_meas);
            % microphone
            app.MicrophonesEditField.Value = num2str(length(app.config.mic));
            app.mics = length(app.config.mic);
            % array dropdown
            app.ArrayrotDropDown.Value = app.config.array_dir;
            eval_array_dropdown(app);
            % input edit field
            app.SynchronousCheckBox.Value = app.config.has_audio_in;
            % passes edit field
            app.PassesEditField.Value = num2str(app.config.passes);
            % starting channel
            app.StartmicEditField.Value = app.config.start_ch;
            % calibration frequency
            app.CalFreqHzEditField.Value = num2str(app.config.calib_freq);            
            % ---- table update
            T = refresh_mic_table(app);
            app.UITable.Data = T; 
            % draw the changes
            drawnow
        end
        % ---- refresh microphone table in GUI
        function T = refresh_mic_table(app)
            % set the amount of microphones
            app.mics = length(app.config.mic);
            % create a table of microphones
            TBL = gen_mic_table(app.mics, app.config.passes, app.config.start_ch);
            for pass_ = 1:app.config.passes % added passes
                for mic = 1:app.mics % write attributes into table
                    ind = mic + app.mics*(pass_-1);
                    TBL.(1)(ind) = pass_;
                    TBL.(2)(ind) = mic;
                    TBL.(3)(ind) = app.config.mic(mic).az(pass_);
                    TBL.(4)(ind) = app.config.mic(mic).el(pass_);
                    TBL.(5)(ind) = app.config.mic(mic).chan;
                end % app.mics
            end % app.config.passes
            % output the edited table
            T = TBL;
            return
        end
        % ---- refreshes the device lirefresh_mst ----
        function refresh_dev_list(app)
            % load the dropdown
            ad_dd = app.ASIODeviceDropDown;  
            % add device names
            ad_dd.Items = app.dev_conf(1).names;
            % add data to each item
            ad_dd.ItemsData = num2cell(1:length(app.dev_conf));
            % draw the updates
            drawnow
        end
        % ---- refreshes available device options ----
        function refresh_fs_buffer_values(app,value)
            % load dropdowns into variables for readability
            b_dd  = app.BufferSizeDropDown;
            fs_dd = app.SamplerateDropDown;
            
            % add item and value pairs
            [b_items,b_values] = list_vals(app.dev_conf(value).buffer);
            [fs_items, fs_values] = list_vals(app.dev_conf(value).fs);

            % available buffer sizes
            b_dd.Items = b_items;
            b_dd.ItemsData = b_values;

            % available samplerates
            fs_dd.Items = fs_items;
            fs_dd.ItemsData = fs_values;
            
        end
        % ---- lock/unlock measurement tab ----
        function meas_lock(app,lock)
            % enables and disables correct elements
            meas_grid = app.MEAS_GRID;
            meas_children = get(meas_grid,'Children');
            if lock == true
                set_children_visibility(app,meas_children,'off')
                app.MEASWARNINGLabel.Visible = 'on';
            else
                set_children_visibility(app,meas_children,'on')
                app.MEASWARNINGLabel.Visible= 'off';
            end
            app.MEASWARNINGLabel.Enable = 'on';
        end
        % ---- lock/unlock calibration tab ----
        function calib_lock(app,lock)
            % enables and disables correct elements
            cal_grid = app.CALIB_GRID;
            cal_children = get(cal_grid,'Children');
            if lock == true
                set_children_visibility(app,cal_children,'off')
                app.CALIBWARNINGLabel.Visible = 'on';
            else
                set_children_visibility(app,cal_children,'on')
                app.CALIBWARNINGLabel.Visible = 'off';
            end
            app.CALIBWARNINGLabel.Enable = 'on';
        end
        % ---- lock/unlock device settings tab ----
        function dev_settings_lock(app,lock)
            % enables and disables correct elements
            if lock == true
                app.SAVEANDCONTINUEButton.Enable = 'off';
                app.RESETButton.Enable = 'on';
                app.ASIOSETTINGSButton.Enable = 'off';
                app.ASIODeviceDropDown.Enable = 'off';
            else
                app.SAVEANDCONTINUEButton.Enable = 'on';
                app.RESETButton.Enable = 'off';
                app.ASIOSETTINGSButton.Enable = 'on';
                app.ASIODeviceDropDown.Enable = 'on';
                refresh_dev_list(app);
                temp = app.PlayRec.getAudioDevices;
            end
        end
        
        % ---- lock/unlock setup settings tab ----
        function setup_lock(app,lock)
            % enables and disables correct elements
            setup_grid = app.SETUP_GRID;
            setup_children = get(setup_grid,'Children');            
            if lock == true
                set_children_visibility(app,setup_children,'off')
                app.SETUPWARNINGLabel.Visible = 'on';
            else
                set_children_visibility(app,setup_children,'on')
                app.RESETButton.Enable = 'off';
                app.SETUPWARNINGLabel.Visible = 'off';
            end
            app.SETUPWARNINGLabel.Enable = 'on';
        end
        
        % ---- lock/unlock microphone map settings ----
        function micmap_settings_lock(app,lock)
            % enables and disables correct elements
            micmap_grid = app.MIC_GRID;
            micmap_children = get(micmap_grid,'Children');          
               
            if lock == true
                set_children_visibility(app,micmap_children,'off')
                app.EDITButton.Enable = 'on';
            else
                set_children_visibility(app,micmap_children,'on')
                app.EDITButton.Enable = 'off';
                setting = app.ArrayrotDropDown.Value;
                if isequal(setting,'Static')
                    app.MeasurementsEditField.Enable = 'off';
                    app.AnglesectordegEditField.Enable = 'off';
                    app.EDITANGLESButton.Enable = 'off';
                else
                    % TODO: investigate if this can be deleted
                    %app.MeasurementsEditField.Enable = 'on';
                    %app.AnglesectordegEditField.Enable = 'on';
                    %app.EDITANGLESButton.Enable = 'on';
                end
            end
        end
        % --- recording function ---
        function rec = long_rec(app,time,in)
            % used to record and or play audio
            if ~exist('in',"var")
                % if the input signal is empty, set it to all 0
                in = zeros(time*app.meas.dev.fs,1);
            end
            
            % buffer size and samplerate
            buffSz = app.meas.dev.buffer_sz;
            Fs = app.meas.dev.fs;
            
            % initialize settings
            full_under = 0;
            full_over = 0;
            time_per = buffsz/Fs;
            buff_amt = time/time_per;
            szDiff = mod(length(in),buffSz);
            if szDiff ~= 0
                % if the size of the last buffer is smaller, fill the
                % buffer with zeros
                in = vertcat(in,zeros(szDiff,1));
            end

            % zero out the recording
            rec = zeros(length(in),app.mics);

            % change inp size co match buffers
            for buff = 1:buff_amt
                low = (buff-1)*buffSz+1; 
                high = buff*buffSz+1;
                disp(buff);
                [out,underruns,overruns] = app.PlayRec(in(low:high));
                if buff == 1
                    % throw away first buffer, this is due to it being the
                    % buffer before the sound starts playing
                    continue
                elseif buff == 2
                    % create data structures to save recording, you cannot
                    % append at the first pass
                    rec(1:app.meas.dev.buffer_sz+1,1:app.mics) = out(:,:);
                    disp(out);
                else
                    %append recodrded buffers to the recording
                    rec(low:high,1:app.mics) = out(:,:);
                end
                
                % add all buffer over/underruns
                full_under = full_under + underruns;
                full_over = full_over + overruns;
                
                % if soundcard cannot handle the samplerate, stop
                if full_under >= 20
                    disp("ERROR: too many buffer underruns")
                    break;
                end                
                if full_over >= 20
                    disp("ERROR: too many buffer overruns")
                    break;
                end
            end
        end
        
        % ----- save configuration structure -----
        function save_config_xml(app,name)
            % processes the selected config data and saves it
            process_mic_data(app,app.UITable.Data);
            save_config(app.config,name);
        end

        % ----- load configuration structure -----
        function load_config_xml(app, name)
            % loads saved config files
            
            % 1. Zjistíme, kde leží tento soubor (aby cesta byla absolutní a ne relativní)
            currentFile = mfilename('fullpath');
            [scriptPath, ~, ~] = fileparts(currentFile);
        
            % 2. Sestavíme cestu bezpečně pomocí fullfile
            % Jdeme: složka_skriptu -> o úroveň výš (..) -> config_files -> jméno souboru
            path = fullfile(scriptPath, '..', 'config_files', name);
            
            % (Volitelné) Debug výpis, abyste viděl, co přesně se načítá
            % fprintf('Načítám config: %s\n', path);
        
            data = load_config(path);           
            
            % Používám ~isempty místo isempty(...) == 0, je to standardnější
            if ~isempty(data)
                app.config = data;
                
                % Předpokládám, že toto je nějaká vaše pomocná funkce
                str_to_logic(app, app.config.has_audio_in);
        
                refresh_config_UI(app);
            else
                % Je dobré vědět, pokud se načtení nepovedlo
                warning('Config soubor nebyl načten nebo je prázdný: %s', path);
            end
        end

        % ----- save measurement structure -----
        function save_meas_mat(app,name)
           save_meas(app.meas,name)
        end

        % ----- load measurement structure -----
        function load_meas_mat(app, name)
            % 1. Zjistíme cestu k tomuto skriptu (složka 'scripts')
            currentFile = mfilename('fullpath');
            [scriptPath, ~, ~] = fileparts(currentFile);
        
            % 2. Sestavíme bezpečnou cestu do vedlejší složky 'measurement_files'
            % fullfile se postará o lomítka i o přechod o úroveň výš (..)
            path = fullfile(scriptPath, '..', 'measurement_files', name);
        
            % 3. Načteme data
            data = load_meas(path);
        
            % 4. Kontrola (používám ~isempty, což je v MATLABu standard pro "není prázdné")
            if ~isempty(data)
                app.meas = data;
                enter_dropdown_values(app);
            else
                % Dobré pro ladění, abyste věděl, proč se nic nestalo
                warning('Soubor měření "%s" je prázdný nebo nebyl nalezen.', path);
            end
        end
        
        % ----- change string value from xml to logic value -----
        function str_to_logic(app,inp_str)
            % transfer from string to logical, so it can be loaded intto
            % a textbox object
            if inp_str == "true"
                app.config.has_audio_in = true;
            elseif inp_str == "false"
                app.config.has_audio_in = false;
            end            
        end

        % ----- map speaker channels -----
        function map_spkr(app,R,L)
            left  = str2double(L);
            right = str2double(R);

            app.config.spkrL = left;
            app.config.spkrR = right;
        end

        % ---- recalculate angles (even distribution) -----
        function recalc_angles(app)
            % initialise values
            n_meas = app.config.n_meas;
            angle = app.config.meas_angle;
            center = app.config.meas_center;
            min_angle = -angle/2+center;
            max_angle = angle/2+center;

            % if there's more than one measurement
            if n_meas > 1
                app.config.angles = round( ...
                    linspace(min_angle, max_angle, n_meas));

            % if there's one measurement
            else
                app.config.angles = center;
            end
        end

        % ----- evaluate microphone array dropdown -----
        function eval_array_dropdown(app)

            value = app.ArrayrotDropDown.Value;

            if isequal(value,'Static')
                % if the array is static
                app.config.n_meas = 1;                
                app.MeasurementsEditField.Enable = "off";    
                app.AnglesectordegEditField.Enable = "off";
                app.EDITANGLESButton.Enable = "off";
            else
                % if the array rotates
                app.config.n_meas = str2double(app.MeasurementsEditField.Value);
                app.MeasurementsEditField.Enable = "on";
                app.AnglesectordegEditField.Enable = "on";
                app.EDITANGLESButton.Enable = "on";
            end
            
            % set angle name
            if isequal(value,'Vertical')
                app.angle_name = "Azimuth";
            else
                app.angle_name = "Elevation";
            end

            % save dropdown value
            app.config.array_dir = value;      
        end

        % ----- refresh analysis dropdowns in review tab -----
        function refresh_analysis_dropdowns(app, value)
            % dropdowns
            pa_dd = app.PASSDropDown;
            mi_dd = app.MICDropDown;
            po_dd = app.POINTDropDown;

            % checkboxes
            pa_cb = app.PASSCheckBox;
            mi_cb = app.MICCheckBox;
            po_cb = app.POSCheckBox;

            % edit fields
            sta_ef = app.StartBandEditField;
            sto_ef = app.StopBandEditField;

            % button used to draw charts
            dr_but = app.DRAWButton;
            
            if value == "FR"
                % frequency response
                toEnable = [pa_dd mi_dd, po_dd, pa_cb mi_cb, po_cb, dr_but];
                toDisable = [sta_ef, sto_ef];
            end

            if value == "FOA"
                % fractional-octave analysis
                toEnable = [pa_dd mi_dd, po_dd, dr_but];
                toDisable = [sta_ef, sto_ef, pa_cb mi_cb, po_cb];
                pa_cb.Value = false;
                mi_cb.Value = false;
                po_cb.Value = false;
            end

            if value == "2DDR"
                % 2D directional response
                toEnable = [pa_dd mi_dd, po_dd, pa_cb mi_cb, po_cb, sta_ef, sto_ef];
                toDisable = [dr_but];
            end

            if value == "3DDR"
                % 3D directional response
                toEnable = [sta_ef, dr_but];
                toDisable = [pa_dd mi_dd, po_dd, pa_cb mi_cb, po_cb, sto_ef];
                pa_cb.Value = false;
                mi_cb.Value = false;
                po_cb.Value = false;
            end

            if value == "IR"
                % impulse response
                toEnable = [sta_ef, sto_ef, dr_but];
                toDisable = [pa_dd mi_dd, po_dd, pa_cb mi_cb, po_cb];
            end

            % elements to deactivate
            for i = 1:numel(toDisable)
                toDisable(i).Enable = 'off';
            end
        
            % elements to activate
            for i = 1:numel(toEnable)
                toEnable(i).Enable = 'on';
            end
        end
        
        % ----- enter dropdown values-----
        function enter_dropdown_values(app)
            % dropdowns
            pa_dd = app.PASSDropDown;
            mi_dd = app.MICDropDown;
            po_dd = app.POINTDropDown;
            
            % values
            app.dr_passes = length(app.meas);
            app.dr_mics   = length(app.meas(1).mic);
            app.dr_points = length(app.meas(1).mic(1).point); 

            for pass = 1:app.dr_passes
                for mic = 1:app.dr_mics
                    for point = 1:app.dr_points
                    
                        % azimuth
                        azim = app.meas(pass).mic(mic).point(point).az;
                        % elevation
                        elev = app.meas(pass).mic(mic).point(point).el;
                        
                        % simplified structure
                        app.pnt(mic).az(point) = azim;
                        app.pnt(mic).el(point) = elev;
                        
                        % add all points
                        app.items.points_dd(pass,mic,point) = strcat( ...
                                                            string(point),...
                                                            " , az: ", ...
                                                            string(azim), ...
                                                            " , el: ", ...
                                                            string(elev));
                        % the initial mic positions
                        if point == 1
                            app.items.mic_dd(pass,mic) = strcat(string(mic), ...
                                                            ", init az: ", ...
                                                            string(azim), ...
                                                            ", init el: ", ...
                                                            string(elev));
                        end
                    end % dr_points                   
                end % dr_mics                
            end % dr_passes

            % TODO: possibly streamline this into a function that takes
            % care of the value and data pairs
            
            % set pass dropdown values
            pa_dd.Items = string(1:app.dr_passes);
            pa_dd.ItemsData = num2cell(1:app.dr_passes);
            
            % set mic dropdown values
            mi_dd.Items = app.items.mic_dd(1,:);
            mi_dd.ItemsData = num2cell(1:app.dr_mics);
            
            % set point dropdown values
            po_dd.Items = reshape(app.items.points_dd(1,1,:),app.dr_points,[]);
            po_dd.ItemsData = num2cell(1:app.dr_passes);
            
        end
        
        % ---- show measure prompt ----
        function show_measure_prompt(app)
            app.LOGTextArea_2.Value = "To measure the first measurement press the measure button";
            drawnow
        end
        
        % TODO: fix comments
        % ---- load a measurement tone
        function meas_tone_load(app)
            % 1. Zjistíme, kde se nachází tento soubor (aplikace/skript)
            % 'fullpath' vrátí celou cestu včetně názvu souboru
            currentFile = mfilename('fullpath');
            
            % 2. Získáme adresář, ve kterém tento soubor leží (tj. složka 'scripts')
            [scriptPath, ~, ~] = fileparts(currentFile);
            
            % 3. Sestavíme cestu k measurement_tones
            % Jdeme ze 'scripts' o úroveň výš ('..') a pak do 'measurement_tones'
            pathToWavs = fullfile(scriptPath, '..', 'measurement_tones', '*.wav');
            
            % DEBUG: Pro jistotu vypíšeme do konzole, kam se reálně díváme
            fprintf('Hledám soubory v: %s\n', pathToWavs);
        
            % 4. Načteme soubory
            filesStruct = dir(pathToWavs);
            
            % Ošetření, pokud složka neexistuje nebo je prázdná
            if isempty(filesStruct)
                warning('Nebyly nalezeny žádné .wav soubory!');
                app.MEASTONEDropDown.Items = {};
                app.MEASTONEDropDown.ItemsData = {};
                return;
            end
        
            directory = struct2table(filesStruct);
            
            % Získání jmen souborů
            filenames = directory.name;
            
            % 5. Úprava stringů pro zobrazení (Items)
            % Převedeme na string array, pokud to ještě není (pro jistotu u starších verzí)
            displayNames = string(filenames);
            displayNames = replace(displayNames, "_", " ");
            displayNames = replace(displayNames, ".wav", "");
            
            % 6. Nastavení DropDownu
            % Items = to co vidí uživatel (bez podtržítek a přípony)
            % ItemsData = skutečné názvy souborů (pro načtení)
            app.MEASTONEDropDown.Items = displayNames;
            app.MEASTONEDropDown.ItemsData = filenames;
        end
        
        % ----- set children visibility of an object -----
        function set_children_visibility(app,children,onoff)
            for i = 1:numel(children)
                comp = children(i);
                % enable/disable only the components that can be enabled/disabled
                if isprop(comp, 'Enable')  
                    comp.Enable = onoff;
                end
            end
        end

        % Analyze and plot results
        function plot_analysis(app, value)
            if ~isempty(app.pnt) % If a measurement is loaded
                
                % --- 1. UI & Configuration Retrieval ---
                % Dropdowns
                pass_idx  = app.PASSDropDown.Value;
                mic_idx   = app.MICDropDown.Value;
                point_idx = app.POINTDropDown.Value;
                
                % Checkboxes (Select All logic)
                ALL_pass  = app.PASSCheckBox.Value;
                ALL_mic   = app.MICCheckBox.Value;
                ALL_point = app.POSCheckBox.Value;
                
                % Default values
                passes = 1; 
                mics = 1; 
                points = 1;
        
                % --- 2. Determine Measurement Type ---
                if isfield(app.meas, "type")
                    type = app.meas(1).type;
                else
                    % Backwards compatibility for old measurements
                    type = "async";
                end
        
                % --- 3. Data Fetching Logic ---
                data = struct();
                
                % Logic to select specific data based on checkboxes
                if ALL_pass
                    passes = 1:app.dr_passes;
                    for p = passes
                        data.sig(:, p) = app.meas(p).mic(mic_idx).point(point_idx).sig(:);
                        data.cal(p) = app.meas(p).mic(mic_idx).calib;
                        data.az(p) = app.pnt(mic_idx).el(point_idx); % Assuming mapping logic
                        data.el(p) = app.pnt(mic_idx).az(point_idx);
                        
                        leg_lbls(p) = strcat("Pass: ", num2str(p), ...
                                             " Azim: ", num2str(data.az(p)), "°");
                    end
                elseif ALL_mic
                    mics = 1:app.dr_mics;
                    for m = mics
                        data.sig(:, m) = app.meas(pass_idx).mic(m).point(point_idx).sig(:);
                        data.cal(m) = app.meas(pass_idx).mic(m).calib;
                        data.az(m) = app.pnt(m).el(point_idx);
                        data.el(m) = app.pnt(m).az(point_idx);
                        leg_lbls(m) = strcat("Mic: ", num2str(m));
                    end
                elseif ALL_point
                    points = 1:app.dr_points;
                    for pt = points
                        data.sig(:, pt) = app.meas(pass_idx).mic(mic_idx).point(pt).sig(:);
                        data.cal(1) = app.meas(pass_idx).mic(mic_idx).calib;
                        data.az(pt) = app.pnt(mic_idx).el(pt);
                        data.el(pt) = app.pnt(mic_idx).az(pt);
                        
                        leg_lbls(pt) = strcat("Point: ", num2str(pt), ...
                                              " Azim: ", num2str(data.az(pt)), "°");
                    end
                else
                    % Single selection
                    data.sig(:,1) = app.meas(pass_idx).mic(mic_idx).point(point_idx).sig;
                    data.cal(1) = app.meas(pass_idx).mic(mic_idx).calib;
                    data.az(1) = app.pnt(mic_idx).el(point_idx);
                    data.el(1) = app.pnt(mic_idx).az(point_idx);
                    leg_lbls = "Single Measurement";
                end
                
                data.fs = app.meas(1).dev.fs;
        
                % --- 4. Signal Processing & Analysis ---
                
                % A) Get Linear Spectrum (FFT)
                if strcmp(type, "async")
                    [x_freq, ~, y_lin] = calc_freq_resp_async(data);  
                else
                    % Synchronous measurement
                    [x_freq, ~, y_lin] = plot_freq_resp_sync(data);
                end
        
                % B) Fractional Octave Band Calculation (Energy Summation)
                % Retrieve band definitions ONCE (optimization)
                [cf, uf, lf] = fract_bands(app); 
                num_bands = length(cf);
                num_channels = size(y_lin, 2);
                
                % Pre-allocate for speed
                band_db_vals = zeros(num_bands, num_channels);
                
                % Loop through each measurement channel (e.g., each angle)
                for ch = 1:num_channels
                    current_spectrum = y_lin(:, ch);
                    rms_vals = zeros(num_bands, 1);
                    
                    % Loop through each frequency band
                    for b = 1:num_bands
                        % Get indices of FFT bins within the current band
                        inds = f_to_ind(app, x_freq, lf(b), uf(b));
                        
                        if ~isempty(inds)
                            % --- CRITICAL FIX ---
                            % Use norm() instead of mean/rms.
                            % We must SUM the energy of bins in the band, not average them.
                            % norm(v) = sqrt(sum(v.^2))
                            % Factor 2 is kept for one-sided spectrum compensation.
                            rms_vals(b) = 2 * norm(current_spectrum(inds));
                        else
                            rms_vals(b) = 0;
                        end
                    end
                    
                    % Convert to dB with calibration
                    if length(data.cal) > 1
                        cal_val = data.cal(ch);
                    else
                        cal_val = data.cal(1);
                    end
                    band_db_vals(:, ch) = calc_db(cal_val, rms_vals, true);
                end
        
                % --- 5. Plotting / Output ---
                
                % Determine target axes (App UI vs New Figure)
                target = [];
                if strcmp(app.mode, "app")
                    target = app.UIAxes;
                end
                
                switch value
                    case "FR"
                        % Recalculate full spectrum dB for display
                        y_db_full = calc_db(data.cal(1), y_lin, true);
                        plotFR(x_freq, y_db_full, leg_lbls, target);
                
                    case "FOA"
                        % Fractional Octave Analysis
                        plotFOA(cf, band_db_vals, target);
                    
                    case "2DDR"
                        % 2D Directivity Response
                        plotDirectional2D(data, band_db_vals, cf, ...
                                          app.StartBandEditField.Value, ...
                                          app.StopBandEditField.Value, ...
                                          target);
                    
                    case "3DDR"
                        % 3D Directivity Response
                        plotDirectional3D(data.az, data.el, band_db_vals, target);
                
                    case "EXPORT"
                        exportWavMeasurements(data, app.meas.dev.fs, '../export');
                
                    case "IR" 
                        % Impulse Response
                        ref_info = []; 
                        if strcmp(type, "sync") && isfield(app.meas, 'ref_sig')
                            ref_info = app.meas(1); 
                        end
                        plotIR(data.sig, data.fs, leg_lbls, target, ref_info);   
                end
            end
        end
        % ---- transfer octave band to frequency -----
        function index_arr = f_to_ind(app,freqs,fmin, fmax)
            for i=1:length(freqs)
                if (freqs(i) <= fmin)
                    min_ind = i;
                    max_ind = i;
                end
                if (freqs(i) >= fmax)
                    max_ind = i;
                    break;
                end
            end
            index_arr = (min_ind:max_ind);
        end

        % ---- calculate third octave bands -----
        function [fc,fu,fl] = fract_bands(app)
            fc = 10.^(0.1.*(12:43));
            fd = 10^0.05;
            fu = fc*fd;
            fl = fc/fd;
        end
    end
    methods (Access = public)
        function update_angles(app,angles)
            app.config.angles = angles;
            eval_array_dropdown(app);
        end
        
        % TODO: ukázat všechny atributy
        function upd_calib_table(app)
            nChann = length(app.config.mic);
            Mic(1:nChann) = " ";
            for num = 1:nChann
                Mic(num) = strcat("#",num2str(num), ", Angle: ", num2str(app.config.mic(num).el(1))," el. ",num2str(app.config.mic(num).az(1))," az.");
            end
           
            Mic = Mic';
            SNR = zeros(app.mics,1);
            Calibrated = false(app.mics,1);
            Noise = zeros(app.mics,1);
            if isfield(app.meas,'mic')
                if ~isempty(app.cal_vect)
                    for mic = 1:app.mics
                        Calibrated(mic) = app.cal_vect(mic);
                    end
                end
                if isfield(app.meas.mic(1),'noise')
                    for mic = 1:app.mics
                        Noise(mic) = app.meas.mic(mic).noise;
                    end
                    disp('noise exists');
                end
                if isfield(app.meas.mic(1),'SNR')
                    for mic = 1:app.mics
                        SNR(mic) = app.meas.mic(mic).SNR;
                    end
                    disp('SNR exists');
                end
            end
            T = table(Mic, Noise, SNR, Calibrated);
     
            app.CALIBTable.Data = T;
            drawnow
        end      
        function set_meas_type(app)
            if app.config.has_audio_in == true
                app.meas(1).type = "sync";
            else
                app.meas(1).type = "async";
            end        
        end
        
        function upd_meas_table(app,meas_vect)
            % measurement number, measurement angle, done
            measN = (1:app.config.n_meas)';
            measA = app.config.angles(:);
            T = table(measN,measA,meas_vect);
            %app.Table = T;
            app.MEASTable.Data = T;
            app.MEASTable.ColumnName = ["Position",app.angle_name,"Measured"];
            drawnow
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init_app(app)
            bufferSizes = [256, 512, 1024];
            sampleRates = [44100, 48000, 96000];

            pause(0); % let app load graphics
            
            % load devices
            app.dev_conf = devices_and_support(sampleRates,bufferSizes);
            refresh_fs_buffer_values(app,1);
            
            % device settings
            app.meas.dev.buffer_sz = app.SamplerateDropDown.Value;
            app.meas.dev.fs = app.BufferSizeDropDown.Value;
            
            
            app.on_thresh = 0;
            app.mics = str2double(app.MicrophonesEditField.Value);
            
            app.PlayRec = audioPlayerRecorder;
            temp = app.PlayRec.getAudioDevices; 
            app.meas.dev.sel_dev = 1;
            refresh_dev_list(app);    
            app.actual_meas = 0;
            app.pass = 0;
            
            % label text
            app.SETUPWARNINGLabel.Text = "SETUP MICROPHONE SETTINGS";
            app.CALIBWARNINGLabel.Text = "SETUP AUDIO DEVICE FIRST";
            app.MEASWARNINGLabel.Text = "CALIBRATE MEASUREMENT ARRAY";
            
            % lock/unlock tabs
            setup_lock(app,true);
            meas_lock(app,true);
            calib_lock(app,true);
            meas_tone_load(app);
            micmap_settings_lock(app,false);
            
            % load analysis dropdowns
            refresh_analysis_dropdowns(app, "FR")
            
            % disable buttons
            app.RESETButton.Enable = 'off';
            app.EDITButton.Enable = 'off';
            
            % map speakers
            map_spkr(app,app.REditField.Value,app.LEditField.Value);
            
            % load measurement settings into respective structures
            app.meas.min_SNR = app.MINIMUMSNRdBEditField.Value;            
            app.meas.headroom  = app.HEADROOMdBFSEditField.Value;
            app.meas.num_clips = app.CLIPSAMPLESEditField.Value;
            app.meas.atten = 10^(app.GaindBKnob.Value/20);

            % load configuration settings into respective structures
            app.config.has_audio_in = app.SynchronousCheckBox.Value;
            app.config.n_meas = str2double(app.MeasurementsEditField.Value);
            app.config.meas_angle = str2double(app.AnglesectordegEditField.Value);
            app.config.meas_center = str2double(app.CenterangledegEditField.Value);
            app.config.array_dir = app.ArrayrotDropDown.Value;
            app.config.passes = str2double(app.PassesEditField.Value);
            app.config.start_ch = app.StartmicEditField.Value;
            app.config.calib_freq = str2double(app.CalFreqHzEditField.Value);

            % set measurement type
            set_meas_type(app);
            % set up microphone table
            app.UITable.Data = gen_mic_table(app.mics,app.config.passes,app.config.start_ch);
            % calculate microphone angles
            recalc_angles(app);
        end

        % Button pushed function: SAVEANDCONTINUEButton
        function SAVEANDCONTINUEButtonPushed(app, event)
            % TODO: make a function to check validity of data
            app.meas_tone = app.MEASTONEDropDown.Value;
            app.meas.dev.fs = app.SamplerateDropDown.Value;
            app.meas.dev.buffer_sz = app.BufferSizeDropDown.Value;
            app.meas_time = app.MeasurementTimesEditField.Value;
            playrec = app.PlayRec;
            app.TabGroup.SelectedTab = app.CALIBRATIONTab;            
            calib_lock(app,false);
            dev_settings_lock(app,true);    
            
            %app.meas.dev.fs = get(playrec).SampleRate;
            app.meas.dev.n_bits = get(playrec).BitDepth;
            %app.meas.dev.buffer_sz = get(playrec).BufferSize;

            %app.meas.dev.fs = 48000; %TODO fixnout toto nějak
            % detekce podporovaných FS, vykreslední do DD
            app.meas.dev.fs
            app.meas.dev.buffer_sz

            app.PlayRec = audioPlayerRecorder(app.meas.dev.fs, ...
                     "BitDepth",app.meas.dev.n_bits, ...
                     "RecorderChannelMapping",[app.config.mic(:).chan], ...
                     "PlayerChannelMapping",[app.config.spkrR app.config.spkrL], ...
                     "Device",app.dev_conf(1).names(app.meas.dev.sel_dev));
            playrec = app.PlayRec;

            app.meas.dev.n_bits = get(playrec).BitDepth;
            %app.meas.dev.sel_dev
            %app.meas.dev.n_bits
            %app.meas.dev.fs
            %app.meas.dev.buffer_sz
            upd_calib_table(app);
            show_measure_prompt(app);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            calib_lock(app,true);
            meas_lock(app,true);
            dev_settings_lock(app,false);
        end

        % Button pushed function: ASIOSETTINGSButton
        function ASIOSETTINGSButtonPushed(app, event)
            asiosettings();
        end

        % Button pushed function: MEASURENOISEFLOORButton
        function MEASURENOISEFLOORButtonPushed(app, event)
            app.LOGTextArea.Value = "Measuring nosie floor...";
            pause(0);
            noise = meas_func(app.meas.dev.fs,app.mics, ...
                              app.meas.dev.buffer_sz, ...
                              app.PlayRec, ...
                              zeros(app.meas.dev.fs*2,1), ...
                              app.meas.dev.fs, ... 
                              app.meas.atten);
            app.LOGTextArea.Value = "Noise floor measurement done!";
            
            noise_RMS = calc_rms2(noise)';
            %app.mics
            for mic = 1:app.mics
                app.meas.mic(mic).noise = calc_db(1,noise_RMS(mic),false);
            end
            upd_calib_table(app);
        end

        % Value changed function: ASIODeviceDropDown
        function ASIODeviceDropDownValueChanged(app, event)
            value = app.ASIODeviceDropDown.Value;
            app.meas.dev.sel_dev = value;
            refresh_fs_buffer_values(app,value);
        end

        % Value changed function: MicrophonesEditField
        function MicrophonesEditFieldValueChanged(app, event)
            value = app.MicrophonesEditField.Value;
            app.UITable.Data = gen_mic_table(str2double(value),app.config.passes,app.config.start_ch);
            app.mics = str2double(value);
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            file = uigetfile('../config_files/*_c.xml');
            if isequal(file,0)  
                % user canceled file select
            else
                load_config_xml(app,file) 
            end            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            file = uiputfile('../config_files/*_c.xml');
            if isequal(file,0)
                % user canceled file select
            else
              save_config_xml(app,file)
            end       
        end

        % Value changed function: ArrayrotDropDown
        function ArrayrotDropDownValueChanged(app, event)
            eval_array_dropdown(app);
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            setup_lock(app,false);
            set_meas_type(app);
            process_mic_data(app,app.UITable.Data);
            app.config.array_dir = app.ArrayrotDropDown.Value;
            micmap_settings_lock(app,true);
            app.mics = str2double(app.MicrophonesEditField.Value);
            app.TabGroup.SelectedTab = app.SETUPTab;
            app.config.has_audio_in = app.SynchronousCheckBox.Value;

            if app.config.has_audio_in == true
                app.MeasurementTimesEditField.Enableapp.meas = "off";
            else
                app.MeasurementTimesEditField.Enable = "on";
            end
        end

        % Value changed function: MeasurementsEditField
        function MeasurementsEditFieldValueChanged(app, event)
            value = app.MeasurementsEditField.Value;
            app.config.n_meas = str2double(value);
            recalc_angles(app);
        end

        % Button pushed function: EDITButton
        function EDITButtonPushed(app, event)
            setup_lock(app,true);
            micmap_settings_lock(app,false);
        end

        % Value changed function: AnglesectordegEditField
        function AnglesectordegEditFieldValueChanged(app, event)
            value = app.AnglesectordegEditField.Value;
            app.config.meas_angle = str2double(value);
            recalc_angles(app);
        end

        % Button pushed function: CALIBRATEButton
        function CALIBRATEButtonPushed(app, event)
            app.LOGTextArea.Value = "Calibrating...";
            pause(0);
            % initiate calibration for row of mics
            for mic = 1:app.mics
                app.meas.mic(mic).calib = false;
            end
            pause(0);
            calib_const = calibrate_func(app.meas.dev.fs, app.mics, app.meas.dev.buffer_sz, app.PlayRec, app.config.calib_freq);
            for mic = 1:app.mics
                app.meas.mic(mic).calib = calib_const(mic);
            end
            app.LOGTextArea.Value = "Calibration done";
        end

        % Button pushed function: TESTSOUNDSNRButton
        function TESTSOUNDSNRButtonPushed(app, event)
            app.LOGTextArea.Value = "Testing SNR...";
            pause(0);
            
            max_noise = -200;
            
            for mic = 1:app.mics
                app.meas.mic(mic).noise
                max_noise = max(app.meas.mic(mic).noise,max_noise);
            end
            onset_threshold = 10^((max_noise + 20)/20);
            app.on_thresh = onset_threshold;
            disp(strcat("Onset threshold: ",num2str(onset_threshold)))
            if app.config.has_audio_in == true
                % performs measurement with signal going to source
                fname = strcat('../measurement_tones/', app.meas_tone);
                [sig,sig_Fs] = audioread(fname);
                signal = meas_func(app.meas.dev.fs, ...
                               app.mics, ...
                               app.meas.dev.buffer_sz, ...
                               app.PlayRec, ...
                               sig, ...
                               sig_Fs, ...
                               app.meas.atten);
            else
                % performs asynchronous measurement, waits for input
                signal = meas_cont(app.meas.dev.fs, ...
                               app.mics, ...
                               app.meas.dev.buffer_sz, ...
                               app.PlayRec, ...
                               onset_threshold, ...
                               app.meas_time);
            end
            
            if(clip_detect(signal,app.meas.headroom,app.meas.num_clips))
                app.CLIPLamp.Color = [1 0 0];
                app.LOGTextArea.Value = "Signal is clipping";
            else
                app.CLIPLamp.Color = [0 0 0];
                signal_dB = calc_db(1,calc_rms2(signal),false);
                %app.meas.mic(1).noise
                %signal_dB(1),

                % calculate SNR
                for mic = 1:app.mics
                    app.meas.mic(mic).SNR = signal_dB(mic) - app.meas.mic(mic).noise;
                end
                app.meas.mic(:).SNR
                min_SNR = 200;

                % get minimal SNR
                for mic = 1:app.mics
                    min_SNR = min(min_SNR,app.meas.mic(mic).SNR);
                end
              
                if min_SNR >= app.meas.min_SNR
                    msg = strcat ("Signal is in spec, min SNR is, ", ...
                            num2str(min_SNR));                              
                    app.LOGTextArea.Value = msg;                    
                else
                    app.LOGTextArea.Value = strcat ("Did not hit SNR target, min SNR is, ", num2str(min_SNR));
                end
                upd_calib_table(app);
            end
        end

        % Value changed function: GaindBKnob
        function GaindBKnobValueChanged(app, event)
            %convert dB to multiplier
            app.meas.atten = 10^(app.GaindBKnob.Value/20); 
            app.GainEditField.Value = app.GaindBKnob.Value;
        end

        % Button pushed function: MEASUREButton
        function MEASUREButtonPushed(app, event)
            % TODO: if measurement pass is finished notify
            if app.actual_meas == 0
                % first measurement
                if app.config.has_audio_in
                    fname = strcat('../measurement_tones/', app.meas_tone);
                    [sig,sig_Fs] = audioread(fname);
                    app.meas(1).ref_sig = sig;
                    app.meas(1).ref_fs  = sig_Fs;  
                end
                app.RESETButton_2.Enable = 'off';
            end
            if (app.actual_meas < app.config.n_meas)
                % next measurements
                app.LOGTextArea_2.Value = strcat( "Measurement number ", num2str(app.actual_meas+1), ' started');
                pause(0);
                
                if app.config.has_audio_in
                    % synchronous measurement
                    % performs measurement with signal going to source                    
                    signal = meas_func(app.meas.dev.fs, ...
                                   app.mics, ...
                                   app.meas.dev.buffer_sz, ...
                                   app.PlayRec, ...
                                   sig, ...
                                   sig_Fs,...
                                   app.meas.atten);
                else
                    % asynchronous measurement
                    % performs continuous measurement
                    signal = meas_cont(app.meas.dev.fs, ...
                                   app.mics, ...
                                   app.meas.dev.buffer_sz, ...
                                   app.PlayRec, ...
                                   app.on_thresh, ...
                                   app.meas_time);
                end
                %name = strcat('meas1_main_test_n_',num2str(app.actual_meas),'.mat');
                %save(name,"signal");
                % TODO: namapovat a ukázat hondoty
                [az,el] = eval_angle(app);
                
                % write data to structure
                for mic = 1:app.mics                    
                    app.meas(app.pass+1).mic(mic).point(app.actual_meas+1).az = az(mic);
                    app.meas(app.pass+1).mic(mic).point(app.actual_meas+1).el = el(mic);
                    app.meas(app.pass+1).mic(mic).point(app.actual_meas+1).sig(:) = signal(:,mic);
                end
                
                % continue to next measurement
                app.actual_meas = 1 + app.actual_meas;
                finished = app.actual_meas;
                left = app.config.n_meas - app.actual_meas;
                % checkboxes for measured data
                if left <= 0
                    meas_vect = true(app.config.n_meas ,1);
                else
                    meas_vect = [true(finished,1); false(left,1)];
                end
                app.upd_meas_table(meas_vect);
                app.LOGTextArea_2.Value = strcat( "Measurement number ", num2str(app.actual_meas), ' is done, continue to next measurement by pressing the measure button again');  
            end
            
            if (app.actual_meas >= app.config.n_meas)
                % if all measurements from pass are done
                app.pass = app.pass+1;
                app.actual_meas = 0;
                if (app.pass >= app.config.passes)
                    % if all passes are done
                    app.LOGTextArea_2.Value = strcat("The measurement is finished!");
                    app.RESETButton_2.Enable = 'on';
                    app.MEASUREButton.Enable = 'off';
                else
                    app.LOGTextArea_2.Value = strcat("Measurement pass ", num2str(app.pass)," is done, continue to next measurement by pressing the measure button again!");
                end
            end
        end

        % Button pushed function: FINISHANDCONTINUEButton
        function FINISHANDCONTINUEButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MEASUREMENTTab;
            app.meas_tone = app.MEASTONEDropDown.Value;
            app.actual_meas = 0;
            app.pass = 0;
            calib_lock(app,true);
            meas_lock(app,false);
            %app.MEASTable.ColumnName = ["Position",app.angle_name,"Measured"];
            app.upd_meas_table(false(app.config.n_meas,1));
            app.RESETButton_2.Enable = 'off';
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            map_spkr(app,app.REditField.Value,app.LEditField.Value);
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            map_spkr(app,app.REditField.Value,app.LEditField.Value);
        end

        % Value changed function: MINIMUMSNRdBEditField
        function MINIMUMSNRdBEditFieldValueChanged(app, event)
            app.meas.min_SNR = app.MINIMUMSNRdBEditField.Value;   
        end

        % Value changed function: CLIPSAMPLESEditField
        function CLIPSAMPLESEditFieldValueChanged(app, event)
            app.meas.num_clips = app.CLIPSAMPLESEditField.Value;
        end

        % Value changed function: HEADROOMdBFSEditField
        function HEADROOMdBFSEditFieldValueChanged(app, event)
            value = app.HEADROOMdBFSEditField.Value;
            if (value > 0); value = 0; end
            app.meas.headroom = value;
            app.HEADROOMdBFSEditField.Value = value;      
        end

        % Button pushed function: EDITANGLESButton
        function EDITANGLESButtonPushed(app, event)
            app.EDITANGLESButton.Enable = 'off';
            app.MeasurementsEditField.Enable = 'off';
            app.AnglesectordegEditField.Enable = 'off';
            app.angl_popup = SMDRSS_anglePopup(app,app.config.angles');
        end

        % Button pushed function: RESETButton_2
        function RESETButton_2Pushed(app, event)
            show_measure_prompt(app);
            app.actual_meas = 0;
            app.passes = 0;
            app.RESETButton_2.Enable = 'off';
        end

        % Value changed function: CenterangledegEditField
        function CenterangledegEditFieldValueChanged(app, event)
            value = app.CenterangledegEditField.Value;
            app.config.meas_center = str2double(value);
            recalc_angles(app);
        end

        % Button pushed function: SAVEMEASUREMENTButton
        function SAVEMEASUREMENTButtonPushed(app, event)
            % save measurement.mat
            file = uiputfile('../measurement_files/*_m.mat');
            if isequal(file,0)  
                % user canceled file select
            else
                save_meas_mat(app,file)
            end   
        end

        % Value changed function: SynchronousCheckBox
        function SynchronousCheckBoxValueChanged(app, event)
        app.config.has_audio_in = app.SynchronousCheckBox.Value;
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            %convert dB to multiplier
            app.meas.atten = 10^(app.GainEditField.Value/20); 
            app.GaindBKnob.Value = app.GainEditField.Value;
        end

        % Value changed function: PassesEditField
        function PassesEditFieldValueChanged(app, event)
            app.config.passes = str2double(app.PassesEditField.Value);   
            app.UITable.Data = gen_mic_table(app.mics,app.config.passes,app.config.start_ch);
        end

        % Value changed function: StartmicEditField
        function StartmicEditFieldValueChanged(app, event)
            value = app.StartmicEditField.Value; 
            % limit value
            if (value < 1); value = 1; end
            if (value > 127); value = 127; end
            app.config.start_ch = value; 
            app.UITable.Data = gen_mic_table(app.mics,app.config.passes,app.config.start_ch);
            app.StartmicEditField.Value = value;
        end

        % Value changed function: CalFreqHzEditField
        function CalFreqHzEditFieldValueChanged(app, event)
            app.config.calib_freq = str2double(app.CalFreqHzEditField.Value);
        end

        % Value changed function: BufferSizeDropDown
        function BufferSizeDropDownValueChanged(app, event)
            value = app.BufferSizeDropDown.Value;
            app.meas.dev.buffer_sz = value;
        end

        % Value changed function: SamplerateDropDown
        function SamplerateDropDownValueChanged(app, event)
            value = app.SamplerateDropDown.Value;
            app.meas.dev.fs = value;
        end

        % Value changed function: ANALYSISDropDown
        function ANALYSISDropDownValueChanged(app, event)
            value = app.ANALYSISDropDown.Value;
            refresh_analysis_dropdowns(app, value);
            %plot_analysis(app, value);
        end

        % Button pushed function: LOADMEASUREMENTButton
        function LOADMEASUREMENTButtonPushed(app, event)
            % TODO TODO
            file = uigetfile('../measurement_files/*_m.mat');
            if isequal(file,0)  
            % user canceled file select
            else
                load_meas_mat(app,file) 
            end
            app.mode = "app"; 
        end

        % Value changed function: MEASTONEDropDown
        function MEASTONEDropDownValueChanged(app, event)
            app.meas_tone = app.MEASTONEDropDown.Value;
        end

        % Value changed function: MeasurementTimesEditField
        function MeasurementTimesEditFieldValueChanged(app, event)
            app.meas_time = app.MeasurementTimesEditField.Value;            
        end

        % Button pushed function: DRAWButton
        function DRAWButtonPushed(app, event)
            app.mode = "app";
            value = app.ANALYSISDropDown.Value;
            plot_analysis(app, value)
        end

        % Value changed function: MICCheckBox, PASSCheckBox, POSCheckBox
        function only_one_cb(app, event)
            pa_cb = app.PASSCheckBox;
            mi_cb = app.MICCheckBox;
            po_cb = app.POSCheckBox;
            src   = event.Source;
            
            if pa_cb ~= src; pa_cb.Value = false; end
            if mi_cb ~= src; mi_cb.Value = false; end
            if po_cb ~= src; po_cb.Value = false; end
        end

        % Value changed function: PASSDropDown
        function PASSDropDownValueChanged(app, event)
            pass  = app.PASSDropDown.Value;
            mic   = app.MICDropDown.Value;
            point = app.POINTDropDown.Value;

            app.MICDropDown.Items = app.items.mic_dd(pass,:);
            app.MICDropDown.ItemsData = 1:app.dr_mics;
            app.POINTDropDown.Items = reshape(app.items.points_dd(1,1,:),app.dr_points,[]);
            app.POINTDropDown.ItemsData = 1:app.dr_points;
        end

        % Value changed function: MICDropDown
        function MICDropDownValueChanged(app, event)
            pass  = app.PASSDropDown.Value;
            mic   = app.MICDropDown.Value;
            point = app.POINTDropDown.Value;

            app.MICDropDown.Items = app.items.mic_dd(pass,:);
            app.MICDropDown.ItemsData = 1:app.dr_mics;
            app.POINTDropDown.Items = reshape(app.items.points_dd(1,1,:),app.dr_points,[]);
            app.POINTDropDown.ItemsData = 1:app.dr_points;
        end

        % Button pushed function: DRAWINWINDOWButton
        function DRAWINWINDOWButtonPushed(app, event)
            app.mode = "window";
            value = app.ANALYSISDropDown.Value;
            plot_analysis(app, value)
        end

        % Value changed function: StartBandEditField
        function StartBandEditFieldValueChanged(app, event)
            value = app.StartBandEditField.Value;
            if strcmp(app.StopBandEditField.Enable, 'on')
                if app.StopBandEditField.Value < value
                    app.StopBandEditField.Value = value;
                end
            end
        end

        % Value changed function: StopBandEditField
        function StopBandEditFieldValueChanged(app, event)
            value = app.StopBandEditField.Value;
            if app.StartBandEditField.Value > value
                app.StopBandEditField.Value = app.StartBandEditField.Value;
            end
        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)
            plot_analysis(app, "EXPORT")
        end

        % Button pushed function: RETRYMEASUREMENTButton
        function RETRYMEASUREMENTButtonPushed(app, event)
            % if measurement is happening decrement measurement number
            % if measurement is 0 move decrement pass number and set
            % measurement to max
            % if pass == 0 and measurement = 0 do nothing

            if app.actual_meas > 0
                app.actual_meas = app.actual_meas-1;
            else 
                app.actual_meas = app.config.n_meas;
                if app.pass > 0
                    app.pass = app.pass-1;
                end
            end

        end

        % Button pushed function: RETRYPASSButton
        function RETRYPASSButtonPushed(app, event)
            % if measurement is happening
            % retry whole pass meaning set actual_meas to 0 but keep pass
            % number
            app.actual_meas = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MICROPHONEMAPPINGTab = uitab(app.TabGroup);
            app.MICROPHONEMAPPINGTab.Title = 'MICROPHONE MAPPING';
            app.MIC_GRID = uigridlayout(app.MICROPHONEMAPPINGTab);
            app.UITable = uitable(app.MIC_GRID);
            app.UITable.ColumnName = {'Pass number'; 'Microphone number'; 'Azimuth'; 'Elevation'; 'Channel mapping'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true true true];
            app.UITable.Tooltip = {'Enter microphone settings here'};
            app.LOADButton = uibutton(app.MIC_GRID, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Tooltip = {'Load setup preset'};
            app.LOADButton.Text = 'LOAD';
            app.SAVEButton = uibutton(app.MIC_GRID, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Tooltip = {'Save setup preset'};
            app.SAVEButton.Text = 'SAVE';
            app.APPLYButton = uibutton(app.MIC_GRID, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Tooltip = {'Apply selected settings'};
            app.APPLYButton.Text = 'APPLY';
            app.EDITButton = uibutton(app.MIC_GRID, 'push');
            app.EDITButton.ButtonPushedFcn = createCallbackFcn(app, @EDITButtonPushed, true);
            app.EDITButton.Tooltip = {'Edit settings after applying'};
            app.EDITButton.Text = 'EDIT';
            app.SpeakerchannelsLabel = uilabel(app.MIC_GRID);
            app.SpeakerchannelsLabel.Text = 'Speaker channels : ';
            app.EDITANGLESButton = uibutton(app.MIC_GRID, 'push');
            app.EDITANGLESButton.ButtonPushedFcn = createCallbackFcn(app, @EDITANGLESButtonPushed, true);
            app.EDITANGLESButton.Tooltip = {'Customize measurement angles'};
            app.EDITANGLESButton.Text = 'EDIT ANGLES';
            app.TiphoveroverelementstoseemoreinfoLabel = uilabel(app.MIC_GRID);
            app.TiphoveroverelementstoseemoreinfoLabel.Text = {'Tip : hover over elements'; ' to see more info'};
            app.SynchronousCheckBox = uicheckbox(app.MIC_GRID);
            app.SynchronousCheckBox.ValueChangedFcn = createCallbackFcn(app, @SynchronousCheckBoxValueChanged, true);
            app.SynchronousCheckBox.Text = 'Synchronous';
            app.MicrophonesEditFieldLabel = uilabel(app.MIC_GRID);
            app.MicrophonesEditFieldLabel.Tooltip = {'Amount of microphones'};
            app.MicrophonesEditFieldLabel.Text = 'Microphones';
            app.MicrophonesEditField = uieditfield(app.MIC_GRID, 'text');
            app.MicrophonesEditField.InputType = 'digits';
            app.MicrophonesEditField.ValueChangedFcn = createCallbackFcn(app, @MicrophonesEditFieldValueChanged, true);
            app.MicrophonesEditField.Tooltip = {'Amount of microphones'};
            app.MicrophonesEditField.Value = '1';
            app.ArrayrotDropDownLabel = uilabel(app.MIC_GRID);
            app.ArrayrotDropDownLabel.Tooltip = {'Select how your measurement array is set up'};
            app.ArrayrotDropDownLabel.Text = 'Array rot';
            app.ArrayrotDropDown = uidropdown(app.MIC_GRID);
            app.ArrayrotDropDown.Items = {'Horizontal', 'Vertical', 'Static'};
            app.ArrayrotDropDown.ValueChangedFcn = createCallbackFcn(app, @ArrayrotDropDownValueChanged, true);
            app.ArrayrotDropDown.Tooltip = {'Select how your measurement array is set up'};
            app.ArrayrotDropDown.Value = 'Horizontal';
            app.MeasurementsEditFieldLabel = uilabel(app.MIC_GRID);
            app.MeasurementsEditFieldLabel.Tooltip = {'Number of measurements'};
            app.MeasurementsEditFieldLabel.Text = 'Measurements';
            app.MeasurementsEditField = uieditfield(app.MIC_GRID, 'text');
            app.MeasurementsEditField.ValueChangedFcn = createCallbackFcn(app, @MeasurementsEditFieldValueChanged, true);
            app.MeasurementsEditField.Tooltip = {'Number of measurements'};
            app.MeasurementsEditField.Value = '1';
            app.AnglesectordegEditFieldLabel = uilabel(app.MIC_GRID);
            app.AnglesectordegEditFieldLabel.Tooltip = {'Measurement angle in degrees'};
            app.AnglesectordegEditFieldLabel.Text = 'Angle sector (deg)';
            app.AnglesectordegEditField = uieditfield(app.MIC_GRID, 'text');
            app.AnglesectordegEditField.InputType = 'digits';
            app.AnglesectordegEditField.ValueChangedFcn = createCallbackFcn(app, @AnglesectordegEditFieldValueChanged, true);
            app.AnglesectordegEditField.Tooltip = {'Measurement angle in degrees'};
            app.AnglesectordegEditField.Value = '90';
            app.LEditFieldLabel = uilabel(app.MIC_GRID);
            app.LEditFieldLabel.Tooltip = {'Left output channel'};
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.MIC_GRID, 'text');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Tooltip = {'Left output channel'};
            app.LEditField.Value = '1';
            app.REditFieldLabel = uilabel(app.MIC_GRID);
            app.REditFieldLabel.Tooltip = {'Right output channel'};
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.MIC_GRID, 'text');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.REditField.Tooltip = {'Right output channel'};
            app.REditField.Value = '2';
            app.CenterangledegEditFieldLabel = uilabel(app.MIC_GRID);
            app.CenterangledegEditFieldLabel.Tooltip = {'Measurement angle in degrees'};
            app.CenterangledegEditFieldLabel.Text = 'Center angle (deg)';
            app.CenterangledegEditField = uieditfield(app.MIC_GRID, 'text');
            app.CenterangledegEditField.InputType = 'digits';
            app.CenterangledegEditField.ValueChangedFcn = createCallbackFcn(app, @CenterangledegEditFieldValueChanged, true);
            app.CenterangledegEditField.Tooltip = {'Measurement angle in degrees'};
            app.CenterangledegEditField.Value = '0';
            app.PassesEditFieldLabel = uilabel(app.MIC_GRID);
            app.PassesEditFieldLabel.Tooltip = {'Number of measurements'};
            app.PassesEditFieldLabel.Text = 'Passes';
            app.PassesEditField = uieditfield(app.MIC_GRID, 'text');
            app.PassesEditField.ValueChangedFcn = createCallbackFcn(app, @PassesEditFieldValueChanged, true);
            app.PassesEditField.Tooltip = {'Number of measurements'};
            app.PassesEditField.Value = '1';
            app.StartmicrophonechannelsfromLabel = uilabel(app.MIC_GRID);
            app.StartmicrophonechannelsfromLabel.Text = 'Mic chan. from';
            app.StartmicEditField = uieditfield(app.MIC_GRID, 'numeric');
            app.StartmicEditField.ValueChangedFcn = createCallbackFcn(app, @StartmicEditFieldValueChanged, true);
            app.StartmicEditField.Value = 1;
            app.CalibrationFrequencyHzLabel = uilabel(app.MIC_GRID);
            app.CalibrationFrequencyHzLabel.Tooltip = {'Callibrator frequency'};
            app.CalibrationFrequencyHzLabel.Text = 'Calib. freq. (Hz)';
            app.CalFreqHzEditField = uieditfield(app.MIC_GRID, 'text');
            app.CalFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @CalFreqHzEditFieldValueChanged, true);
            app.CalFreqHzEditField.Tooltip = {'Calibrator frequency'};
            app.CalFreqHzEditField.Value = '1000';
            app.SETUPTab = uitab(app.TabGroup);
            app.SETUPTab.Title = 'SETUP';
            app.SETUP_GRID = uigridlayout(app.SETUPTab);
            app.SPACEFORMICARMROTATIONLabel = uilabel(app.SETUP_GRID);
            app.SPACEFORMICARMROTATIONLabel.Text = 'SPACE FOR MIC ARM ROTATION';
            app.SAVEANDCONTINUEButton = uibutton(app.SETUP_GRID, 'push');
            app.SAVEANDCONTINUEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEANDCONTINUEButtonPushed, true);
            app.SAVEANDCONTINUEButton.Tooltip = {'Save settings and continue'};
            app.SAVEANDCONTINUEButton.Text = 'SAVE AND CONTINUE';
            app.RESETButton = uibutton(app.SETUP_GRID, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Tooltip = {'Reset setup settings'};
            app.RESETButton.Text = 'RESET';
            app.ASIOSETTINGSButton = uibutton(app.SETUP_GRID, 'push');
            app.ASIOSETTINGSButton.ButtonPushedFcn = createCallbackFcn(app, @ASIOSETTINGSButtonPushed, true);
            app.ASIOSETTINGSButton.Text = 'ASIO SETTINGS';
            app.SETUPWARNINGLabel = uilabel(app.SETUP_GRID);
            app.SETUPWARNINGLabel.Text = 'SETUP WARNING';
            app.ASIODeviceDropDownLabel = uilabel(app.SETUP_GRID);
            app.ASIODeviceDropDownLabel.Tooltip = {'ASIO device selection'};
            app.ASIODeviceDropDownLabel.Text = 'ASIO Device';
            app.ASIODeviceDropDown = uidropdown(app.SETUP_GRID);
            app.ASIODeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @ASIODeviceDropDownValueChanged, true);
            app.ASIODeviceDropDown.Tooltip = {'ASIO device selection'};
            app.SamplerateDropDownLabel = uilabel(app.SETUP_GRID);
            app.SamplerateDropDownLabel.Tooltip = {'ASIO device selection'};
            app.SamplerateDropDownLabel.Text = 'Samplerate';
            app.SamplerateDropDown = uidropdown(app.SETUP_GRID);
            app.SamplerateDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplerateDropDownValueChanged, true);
            app.SamplerateDropDown.Tooltip = {'ASIO device selection'};
            app.BufferSizeDropDownLabel = uilabel(app.SETUP_GRID);
            app.BufferSizeDropDownLabel.Tooltip = {'ASIO device selection'};
            app.BufferSizeDropDownLabel.Text = 'Buffer Size';
            app.BufferSizeDropDown = uidropdown(app.SETUP_GRID);
            app.BufferSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @BufferSizeDropDownValueChanged, true);
            app.BufferSizeDropDown.Tooltip = {'ASIO device selection'};
            app.ContinuousMeasurementTimesLabel = uilabel(app.SETUP_GRID);
            app.ContinuousMeasurementTimesLabel.Text = 'Measurement Time (s) ';
            app.MeasurementTimesEditField = uieditfield(app.SETUP_GRID, 'numeric');
            app.MeasurementTimesEditField.Limits = [1 20];
            app.MeasurementTimesEditField.ValueChangedFcn = createCallbackFcn(app, @MeasurementTimesEditFieldValueChanged, true);
            app.MeasurementTimesEditField.Value = 2;
            app.CALIBRATIONTab = uitab(app.TabGroup);
            app.CALIBRATIONTab.Title = 'CALIBRATION';
            app.CALIB_GRID = uigridlayout(app.CALIBRATIONTab);
            app.FINISHANDCONTINUEButton = uibutton(app.CALIB_GRID, 'push');
            app.FINISHANDCONTINUEButton.ButtonPushedFcn = createCallbackFcn(app, @FINISHANDCONTINUEButtonPushed, true);
            app.FINISHANDCONTINUEButton.Text = 'FINISH AND CONTINUE';
            app.MEASURENOISEFLOORButton = uibutton(app.CALIB_GRID, 'push');
            app.MEASURENOISEFLOORButton.ButtonPushedFcn = createCallbackFcn(app, @MEASURENOISEFLOORButtonPushed, true);
            app.MEASURENOISEFLOORButton.Text = 'MEASURE NOISE FLOOR';
            app.CALIBRATEButton = uibutton(app.CALIB_GRID, 'push');
            app.CALIBRATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATEButtonPushed, true);
            app.CALIBRATEButton.Text = 'CALIBRATE';
            app.TESTSOUNDSNRButton = uibutton(app.CALIB_GRID, 'push');
            app.TESTSOUNDSNRButton.ButtonPushedFcn = createCallbackFcn(app, @TESTSOUNDSNRButtonPushed, true);
            app.TESTSOUNDSNRButton.Text = 'TEST SOUND SNR';
            app.CALIBTable = uitable(app.CALIB_GRID);
            app.CALIBTable.ColumnName = {'Mic'; 'Noise (dBFS)'; 'SNR (dB)'; 'Calibrated'};
            app.CALIBTable.RowName = {};
            app.CALIBTable.ColumnEditable = [false false];
            app.CALIBWARNINGLabel = uilabel(app.CALIB_GRID);
            app.CALIBWARNINGLabel.Text = 'CALIB WARNING';
            app.GainEditField = uieditfield(app.CALIB_GRID, 'numeric');
            app.GainEditField.Limits = [-12 6];
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = -6;
            app.MINIMUMSNRdBEditField_2Label = uilabel(app.CALIB_GRID);
            app.MINIMUMSNRdBEditField_2Label.Text = 'MINIMUM SNR dB';
            app.MINIMUMSNRdBEditField = uieditfield(app.CALIB_GRID, 'numeric');
            app.MINIMUMSNRdBEditField.ValueChangedFcn = createCallbackFcn(app, @MINIMUMSNRdBEditFieldValueChanged, true);
            app.MINIMUMSNRdBEditField.Value = 20;
            app.LOGTextAreaLabel = uilabel(app.CALIB_GRID);
            app.LOGTextAreaLabel.Text = 'LOG';
            app.LOGTextArea = uitextarea(app.CALIB_GRID);
            app.HEADROOMdBFSLabel = uilabel(app.CALIB_GRID);
            app.HEADROOMdBFSLabel.Text = 'HEADROOM dBFS';
            app.HEADROOMdBFSEditField = uieditfield(app.CALIB_GRID, 'numeric');
            app.HEADROOMdBFSEditField.ValueChangedFcn = createCallbackFcn(app, @HEADROOMdBFSEditFieldValueChanged, true);
            app.HEADROOMdBFSEditField.Value = -3;
            app.CLIPSAMPLESEditFieldLabel = uilabel(app.CALIB_GRID);
            app.CLIPSAMPLESEditFieldLabel.Text = 'CLIP  SAMPLES';
            app.CLIPSAMPLESEditField = uieditfield(app.CALIB_GRID, 'numeric');
            app.CLIPSAMPLESEditField.ValueChangedFcn = createCallbackFcn(app, @CLIPSAMPLESEditFieldValueChanged, true);
            app.CLIPSAMPLESEditField.Value = 20;
            app.GaindBLabel = uilabel(app.CALIB_GRID);
            app.GaindBLabel.Text = 'Gain (dB)';
            app.GaindBKnob = uiknob(app.CALIB_GRID, 'continuous');
            app.GaindBKnob.Limits = [-12 6];
            app.GaindBKnob.ValueChangedFcn = createCallbackFcn(app, @GaindBKnobValueChanged, true);
            app.GaindBKnob.Value = -6;
            app.CLIPLampLabel = uilabel(app.CALIB_GRID);
            app.CLIPLampLabel.Text = 'CLIP';
            app.CLIPLamp = uilamp(app.CALIB_GRID);
            app.MEASTONEDropDownLabel = uilabel(app.CALIB_GRID);
            app.MEASTONEDropDownLabel.Tooltip = {'Select how your measurement array is set up'};
            app.MEASTONEDropDownLabel.Text = 'MEAS TONE';
            app.MEASTONEDropDown = uidropdown(app.CALIB_GRID);
            app.MEASTONEDropDown.Items = {'tone 1', 'tone 2'};
            app.MEASTONEDropDown.ValueChangedFcn = createCallbackFcn(app, @MEASTONEDropDownValueChanged, true);
            app.MEASTONEDropDown.Tooltip = {'Select how your measurement array is set up'};
            app.MEASTONEDropDown.Value = 'tone 1';
            app.MEASUREMENTTab = uitab(app.TabGroup);
            app.MEASUREMENTTab.Title = 'MEASUREMENT';
            app.MEAS_GRID = uigridlayout(app.MEASUREMENTTab);
            app.MEASUREButton = uibutton(app.MEAS_GRID, 'push');
            app.MEASUREButton.ButtonPushedFcn = createCallbackFcn(app, @MEASUREButtonPushed, true);
            app.MEASUREButton.Text = 'MEASURE';
            app.RESETButton_2 = uibutton(app.MEAS_GRID, 'push');
            app.RESETButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESETButton_2Pushed, true);
            app.RESETButton_2.Text = 'RESET';
            app.MEASTable = uitable(app.MEAS_GRID);
            app.MEASTable.ColumnName = {'Position'; 'Angle'; 'Measured'};
            app.MEASTable.RowName = {};
            app.MEASWARNINGLabel = uilabel(app.MEAS_GRID);
            app.MEASWARNINGLabel.Text = 'MEAS WARNING';
            app.RETRYMEASUREMENTButton = uibutton(app.MEAS_GRID, 'push');
            app.RETRYMEASUREMENTButton.ButtonPushedFcn = createCallbackFcn(app, @RETRYMEASUREMENTButtonPushed, true);
            app.RETRYMEASUREMENTButton.Text = 'RETRY MEASUREMENT';
            app.ADDMEASUREMENTButton = uibutton(app.MEAS_GRID, 'push');
            app.ADDMEASUREMENTButton.Text = 'ADD MEASUREMENT';
            app.AdditionalmeasurementLabel = uilabel(app.MEAS_GRID);
            app.AdditionalmeasurementLabel.Text = 'Additional measurement';
            app.SAVEMEASUREMENTButton = uibutton(app.MEAS_GRID, 'push');
            app.SAVEMEASUREMENTButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEMEASUREMENTButtonPushed, true);
            app.SAVEMEASUREMENTButton.Text = 'SAVE MEASUREMENT';
            app.RETRYPASSButton = uibutton(app.MEAS_GRID, 'push');
            app.RETRYPASSButton.ButtonPushedFcn = createCallbackFcn(app, @RETRYPASSButtonPushed, true);
            app.RETRYPASSButton.Text = 'RETRY PASS';
            app.LOGTextArea_2Label = uilabel(app.MEAS_GRID);
            app.LOGTextArea_2Label.Text = 'LOG';
            app.LOGTextArea_2 = uitextarea(app.MEAS_GRID);
            app.RotationangleEditFieldLabel = uilabel(app.MEAS_GRID);
            app.RotationangleEditFieldLabel.Text = 'Rotation angle';
            app.RotationangleEditField = uieditfield(app.MEAS_GRID, 'numeric');
            app.REVIEWTab = uitab(app.TabGroup);
            app.REVIEWTab.Title = 'REVIEW';
            app.REVIEW_GRID = uigridlayout(app.REVIEWTab);
            app.UIAxes = uiaxes(app.REVIEW_GRID);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DRAWButton = uibutton(app.REVIEW_GRID, 'push');
            app.DRAWButton.ButtonPushedFcn = createCallbackFcn(app, @DRAWButtonPushed, true);
            app.DRAWButton.Text = 'DRAW';
            app.LOADMEASUREMENTButton = uibutton(app.REVIEW_GRID, 'push');
            app.LOADMEASUREMENTButton.ButtonPushedFcn = createCallbackFcn(app, @LOADMEASUREMENTButtonPushed, true);
            app.LOADMEASUREMENTButton.Text = 'LOAD MEASUREMENT';
            app.DRAWINWINDOWButton = uibutton(app.REVIEW_GRID, 'push');
            app.DRAWINWINDOWButton.ButtonPushedFcn = createCallbackFcn(app, @DRAWINWINDOWButtonPushed, true);
            app.DRAWINWINDOWButton.Text = 'DRAW IN WINDOW';
            app.POINTDropDown = uidropdown(app.REVIEW_GRID);
            app.POINTDropDown.Enable = 'off';
            app.MICDropDown = uidropdown(app.REVIEW_GRID);
            app.MICDropDown.ValueChangedFcn = createCallbackFcn(app, @MICDropDownValueChanged, true);
            app.MICDropDown.Enable = 'off';
            app.PASSDropDown = uidropdown(app.REVIEW_GRID);
            app.PASSDropDown.ValueChangedFcn = createCallbackFcn(app, @PASSDropDownValueChanged, true);
            app.PASSDropDown.Enable = 'off';
            app.ANALYSISDropDown = uidropdown(app.REVIEW_GRID);
            app.ANALYSISDropDown.Items = {'Frequency Response', 'Fractional Octave Analysis', '2D Directional Response', '3D Directional Response', 'Impulse Response / Time domain'};
            app.ANALYSISDropDown.ItemsData = {'FR', 'FOA', '2DDR', '3DDR', 'IR'};
            app.ANALYSISDropDown.ValueChangedFcn = createCallbackFcn(app, @ANALYSISDropDownValueChanged, true);
            app.ANALYSISDropDown.Value = 'FR';
            app.MEASUREMENTDropDownLabel_2 = uilabel(app.REVIEW_GRID);
            app.MEASUREMENTDropDownLabel_2.Text = 'Position';
            app.MEASUREMENTDropDownLabel_3 = uilabel(app.REVIEW_GRID);
            app.MEASUREMENTDropDownLabel_3.Text = 'Pass';
            app.MEASUREMENTDropDownLabel_4 = uilabel(app.REVIEW_GRID);
            app.MEASUREMENTDropDownLabel_4.Text = 'Analysis';
            app.MEASUREMENTDropDownLabel_5 = uilabel(app.REVIEW_GRID);
            app.MEASUREMENTDropDownLabel_5.Text = 'Microphone';
            app.PASSCheckBox = uicheckbox(app.REVIEW_GRID);
            app.PASSCheckBox.ValueChangedFcn = createCallbackFcn(app, @only_one_cb, true);
            app.PASSCheckBox.Enable = 'off';
            app.PASSCheckBox.Text = '';
            app.MICCheckBox = uicheckbox(app.REVIEW_GRID);
            app.MICCheckBox.ValueChangedFcn = createCallbackFcn(app, @only_one_cb, true);
            app.MICCheckBox.Enable = 'off';
            app.MICCheckBox.Text = '';
            app.POSCheckBox = uicheckbox(app.REVIEW_GRID);
            app.POSCheckBox.ValueChangedFcn = createCallbackFcn(app, @only_one_cb, true);
            app.POSCheckBox.Enable = 'off';
            app.POSCheckBox.Text = '';
            app.AllLabel = uilabel(app.REVIEW_GRID);
            app.AllLabel.Text = 'All';
            app.EXPORTButton = uibutton(app.REVIEW_GRID, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.StartBandEditFieldLabel = uilabel(app.REVIEW_GRID);
            app.StartBandEditFieldLabel.Text = 'Start Band';
            app.StartBandEditField = uieditfield(app.REVIEW_GRID, 'numeric');
            app.StartBandEditField.Limits = [1 32];
            app.StartBandEditField.ValueChangedFcn = createCallbackFcn(app, @StartBandEditFieldValueChanged, true);
            app.StartBandEditField.Value = 1;
            app.StopBandEditFieldLabel = uilabel(app.REVIEW_GRID);
            app.StopBandEditFieldLabel.Text = 'Stop Band';
            app.StopBandEditField = uieditfield(app.REVIEW_GRID, 'numeric');
            app.StopBandEditField.Limits = [1 32];
            app.StopBandEditField.ValueChangedFcn = createCallbackFcn(app, @StopBandEditFieldValueChanged, true);
            app.StopBandEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SMDRSS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init_app)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end